classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        DeptLabel         matlab.ui.control.Label
        ExportButton      matlab.ui.control.Button
        ApplyButton       matlab.ui.control.Button
        file_name         matlab.ui.control.EditField
        FileNameLabel     matlab.ui.control.Label
        dept              matlab.ui.control.NumericEditField
        revolution        matlab.ui.control.NumericEditField
        RevolutionLabel   matlab.ui.control.Label
        tube_dia          matlab.ui.control.NumericEditField
        TubediamaxLabel   matlab.ui.control.Label
        bend_radius       matlab.ui.control.NumericEditField
        BendRadiusLabel   matlab.ui.control.Label
        coil_radius       matlab.ui.control.NumericEditField
        CoilRadiusLabel   matlab.ui.control.Label
        coil_length       matlab.ui.control.NumericEditField
        CoilLengthLabel   matlab.ui.control.Label
        points_per_mm     matlab.ui.control.NumericEditField
        PointspermmLabel  matlab.ui.control.Label
        UIAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Co_ordinates % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
points_per_mm       = app.points_per_mm.Value;
tube_dia            =      app.tube_dia.Value;
Coil_Radious        =   app.coil_radius.Value;                         % Radius of the slinky coil
Revolution          =    app.revolution.Value;                         % Number of turns
length              =   app.coil_length.Value;
dept                =          app.dept.Value;
dept_bend_radious   =   app.bend_radius.Value;
            

% Compute the slinky coil
slinky_coil = slinky(points_per_mm, tube_dia, Coil_Radious, Revolution, length);

% Compute the turn tube and bends
[turn_tube, bend] = turntube(points_per_mm, tube_dia, Coil_Radious, slinky_coil);
[inlet, bend_in, outlet, bend_out] = con_tube(dept, dept_bend_radious, turn_tube, slinky_coil, points_per_mm);

% Combine all coordinates
app.Co_ordinates = [inlet(:,1:end-1), bend_in(:,1:end-1), slinky_coil(:,1:end-1), bend(:,1:end-1), turn_tube(:,1:end-1), bend_out(:,1:end-1), outlet];


% Plotting the 3D Slinky in the app's UIAxes
plot3( app.UIAxes ,app.Co_ordinates(1,:), app.Co_ordinates(2,:), app.Co_ordinates(3,:), 'r', 'LineWidth', 2);

xlabel( app.UIAxes ,'X-axis');
ylabel( app.UIAxes ,'Y-axis');
zlabel( app.UIAxes ,'Z-axis');
title(  app.UIAxes ,'3D Slinky in Custom Coordinate System');
axis(   app.UIAxes ,'equal');
view(   app.UIAxes , 3);  % Set the view to 3D


        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
paths = uigetdir;
file = app.file_name.Value;
paths = [paths,'\',file, '.txt'];

fileID = fopen(paths, 'w');
fprintf(fileID, '%f %f %f\n', app.Co_ordinates);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PointspermmLabel = uilabel(app.GridLayout);
            app.PointspermmLabel.Text = 'Points per mm';
            app.points_per_mm = uieditfield(app.GridLayout, 'numeric');
            app.CoilLengthLabel = uilabel(app.GridLayout);
            app.CoilLengthLabel.Text = 'Coil Length';
            app.coil_length = uieditfield(app.GridLayout, 'numeric');
            app.CoilRadiusLabel = uilabel(app.GridLayout);
            app.CoilRadiusLabel.Text = 'Coil Radius';
            app.coil_radius = uieditfield(app.GridLayout, 'numeric');
            app.BendRadiusLabel = uilabel(app.GridLayout);
            app.BendRadiusLabel.Text = 'Bend Radius';
            app.bend_radius = uieditfield(app.GridLayout, 'numeric');
            app.TubediamaxLabel = uilabel(app.GridLayout);
            app.TubediamaxLabel.Text = 'Tube dia (max)';
            app.tube_dia = uieditfield(app.GridLayout, 'numeric');
            app.RevolutionLabel = uilabel(app.GridLayout);
            app.RevolutionLabel.Text = 'Revolution';
            app.revolution = uieditfield(app.GridLayout, 'numeric');
            app.dept = uieditfield(app.GridLayout, 'numeric');
            app.FileNameLabel = uilabel(app.GridLayout);
            app.FileNameLabel.Text = 'File Name';
            app.file_name = uieditfield(app.GridLayout, 'text');
            app.ApplyButton = uibutton(app.GridLayout, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.ExportButton = uibutton(app.GridLayout, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.DeptLabel = uilabel(app.GridLayout);
            app.DeptLabel.Text = 'Dept';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end